classdef demo_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Panel_3                       matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        ModulationTypeTextArea        matlab.ui.control.TextArea
        ModulationTypeTextAreaLabel   matlab.ui.control.Label
        GainIQImbalanceTextArea       matlab.ui.control.TextArea
        GainIQImbalanceTextAreaLabel  matlab.ui.control.Label
        PhaseImbalanceTextArea        matlab.ui.control.TextArea
        PhaseImbalanceTextAreaLabel   matlab.ui.control.Label
        EVMTextArea                   matlab.ui.control.TextArea
        EVMTextAreaLabel              matlab.ui.control.Label
        Spectrum                      matlab.ui.control.UIAxes
        CstDiag                       matlab.ui.control.UIAxes
        QRecWfm                       matlab.ui.control.UIAxes
        IRecWfm                       matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        LoadConfigFileCheckBox        matlab.ui.control.CheckBox
        SaveDataCheckBox              matlab.ui.control.CheckBox
        TCPcontrolLabel               matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        ConnectionSwitch              matlab.ui.control.Switch
        ConnectionSwitchLabel         matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        PauseButton                   matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        STATE; %IDLE, CONN, RUN // states for the app state machine, defined in demo_gui_state.m
        STATE_CHANGE;
        TCP_OK;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.STATE = demo_gui_state.IDLE;
            app.TCP_OK = false; % flag of TCP status
            app.StatusLamp.Color = [0, 0, 0];
        end

        % Value changed function: ConnectionSwitch
        function ConnectionSwitchValueChanged(app, event)
            value = app.ConnectionSwitch.Value;
            if value == "On" && app.STATE == demo_gui_state.IDLE % IDLE -> CONN
                app.STATE = demo_gui_state.CONN; % try connecting TCP
                demo_gui_process(app);
            else
                app.TCP_OK = false;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if  app.TCP_OK && app.STATE == demo_gui_state.CONN % CONN -> RUN
                app.STATE = demo_gui_state.RUN;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if (app.STATE == demo_gui_state.CONN || app.state == demo_gui_state.RUN)  % CONN | RUN -> IDLE
                app.STATE = demo_gui_state.IDLE;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {739, 739};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1023, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IRecWfm = uiaxes(app.LeftPanel);
            title(app.IRecWfm, 'I - Received Data')
            ylabel(app.IRecWfm, {'Amp'; ''})
            zlabel(app.IRecWfm, 'Z')
            app.IRecWfm.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.QRecWfm = uiaxes(app.LeftPanel);
            title(app.QRecWfm, 'Q - Received Data')
            ylabel(app.QRecWfm, {'Amp'; ''})
            zlabel(app.QRecWfm, 'Z')
            app.QRecWfm.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.CstDiag = uiaxes(app.LeftPanel);
            title(app.CstDiag, 'Constellation Diagram')
            zlabel(app.CstDiag, 'Z')
            app.CstDiag.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.CstDiag.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.Spectrum = uiaxes(app.LeftPanel);
            title(app.Spectrum, 'Spectrum')
            xlabel(app.Spectrum, 'f(Hz)')
            ylabel(app.Spectrum, 'Magnitude(dB)')
            zlabel(app.Spectrum, 'Z')
            app.Panel_3 = uipanel(app.LeftPanel);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.EVMTextAreaLabel = uilabel(app.GridLayout2);
            app.EVMTextAreaLabel.Text = 'EVM';
            app.EVMTextArea = uitextarea(app.GridLayout2);
            app.PhaseImbalanceTextAreaLabel = uilabel(app.GridLayout2);
            app.PhaseImbalanceTextAreaLabel.Text = 'Phase Imbalance';
            app.PhaseImbalanceTextArea = uitextarea(app.GridLayout2);
            app.GainIQImbalanceTextAreaLabel = uilabel(app.GridLayout2);
            app.GainIQImbalanceTextAreaLabel.Text = 'Gain IQ Imbalance';
            app.GainIQImbalanceTextArea = uitextarea(app.GridLayout2);
            app.ModulationTypeTextAreaLabel = uilabel(app.GridLayout2);
            app.ModulationTypeTextAreaLabel.Text = 'Modulation Type';
            app.ModulationTypeTextArea = uitextarea(app.GridLayout2);
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RefreshButton = uibutton(app.Panel, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.PauseButton = uibutton(app.Panel, 'push');
            app.PauseButton.Text = 'Pause';
            app.Panel_2 = uipanel(app.RightPanel);
            app.StatusLampLabel = uilabel(app.Panel_2);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.Panel_2);
            app.ConnectionSwitchLabel = uilabel(app.Panel_2);
            app.ConnectionSwitchLabel.Text = 'Connection';
            app.ConnectionSwitch = uiswitch(app.Panel_2, 'slider');
            app.ConnectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectionSwitchValueChanged, true);
            app.TCPcontrolLabel = uilabel(app.RightPanel);
            app.TCPcontrolLabel.Text = 'TCP control';
            app.SaveDataCheckBox = uicheckbox(app.RightPanel);
            app.SaveDataCheckBox.Text = 'Save Data';
            app.LoadConfigFileCheckBox = uicheckbox(app.RightPanel);
            app.LoadConfigFileCheckBox.Text = 'Load Config File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end