classdef demo_gui_no_grid_layout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        PortableVectorSignalAnalyzerLabel  matlab.ui.control.Label
        Panel_5                       matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Panel_3                       matlab.ui.container.Panel
        dBTextArea                    matlab.ui.control.TextArea
        dBTextAreaLabel               matlab.ui.control.Label
        TextArea_2                    matlab.ui.control.TextArea
        Label_2                       matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        ModulationTypeTextArea        matlab.ui.control.TextArea
        ModulationTypeTextAreaLabel   matlab.ui.control.Label
        GainIQImbalanceTextArea       matlab.ui.control.TextArea
        GainIQImbalanceTextAreaLabel  matlab.ui.control.Label
        PhaseIQImbalanceTextArea      matlab.ui.control.TextArea
        PhaseIQImbalanceLabel         matlab.ui.control.Label
        EVMTextArea                   matlab.ui.control.TextArea
        EVMTextAreaLabel              matlab.ui.control.Label
        S21                           matlab.ui.control.UIAxes
        CstDiag                       matlab.ui.control.UIAxes
        Panel_4                       matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        SpectrumStatusLamp            matlab.ui.control.Lamp
        SpectrumStatusLampLabel       matlab.ui.control.Label
        NoSignalLabel                 matlab.ui.control.Label
        Spectrum                      matlab.ui.control.UIAxes
        QRecWfm                       matlab.ui.control.UIAxes
        IRecWfm                       matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        TCPcontrolLabel               matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        ConnectionSwitch              matlab.ui.control.Switch
        ConnectionSwitchLabel         matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        LoadConfigFileCheckBox        matlab.ui.control.CheckBox
        SaveDataCheckBox              matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        PauseButton                   matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        STATE; %IDLE, CONN, RUN // states for the app state machine, defined in demo_gui_state.m
        TCP_OK;
        SIGNAL_STATUS;
        PROCESS_COUNT;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.STATE = demo_gui_state.IDLE;
            app.TCP_OK = false; % flag of TCP status
            app.PROCESS_COUNT = 0;
            app.StatusLamp.Color = [0, 0, 0];
            app.SpectrumStatusLamp.Color = [0.5 0.5 0.5];
        end

        % Value changed function: ConnectionSwitch
        function ConnectionSwitchValueChanged(app, event)
            value = app.ConnectionSwitch.Value;
            if value == "On" && app.STATE == demo_gui_state.IDLE % IDLE -> CONN
                app.STATE = demo_gui_state.CONN; % try connecting TCP
                app.StatusLamp.Color = [0, 1, 0];
                demo_gui_process(app);
%             else
%                 app.TCP_OK = false;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if  app.TCP_OK && (app.STATE == demo_gui_state.CONN || app.STATE == demo_gui_state.IDLE)% CONN -> RUN
                app.STATE = demo_gui_state.RUN;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if (app.STATE == demo_gui_state.CONN || app.STATE == demo_gui_state.RUN)  % CONN | RUN -> IDLE
                app.STATE = demo_gui_state.IDLE;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {912, 912};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1592, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.IRecWfm = uiaxes(app.GridLayout5);
            title(app.IRecWfm, 'I - Received Data')
            ylabel(app.IRecWfm, {'Amp'; ''})
            zlabel(app.IRecWfm, 'Z')
            app.QRecWfm = uiaxes(app.GridLayout5);
            title(app.QRecWfm, 'Q - Received Data')
            ylabel(app.QRecWfm, {'Amp'; ''})
            zlabel(app.QRecWfm, 'Z')
            app.Panel_4 = uipanel(app.GridLayout5);
            app.GridLayout4 = uigridlayout(app.Panel_4);
            app.Spectrum = uiaxes(app.GridLayout4);
            title(app.Spectrum, 'Spectrum')
            ylabel(app.Spectrum, {'Amp'; ''})
            zlabel(app.Spectrum, 'Z')
            app.NoSignalLabel = uilabel(app.GridLayout4);
            app.NoSignalLabel.Text = 'No Signal.';
            app.SpectrumStatusLampLabel = uilabel(app.GridLayout4);
            app.SpectrumStatusLampLabel.Text = 'Spectrum Status';
            app.SpectrumStatusLamp = uilamp(app.GridLayout4);
            app.Panel_5 = uipanel(app.GridLayout5);
            app.GridLayout2 = uigridlayout(app.Panel_5);
            app.CstDiag = uiaxes(app.GridLayout2);
            title(app.CstDiag, 'Constellation Diagram')
            xlabel(app.CstDiag, 'I')
            ylabel(app.CstDiag, 'Q')
            zlabel(app.CstDiag, 'Z')
            app.S21 = uiaxes(app.GridLayout2);
            title(app.S21, 'S21')
            xlabel(app.S21, 'f(Hz)')
            ylabel(app.S21, 'magnitude')
            zlabel(app.S21, 'Z')
            app.Panel_3 = uipanel(app.GridLayout2);
            app.EVMTextAreaLabel = uilabel(app.Panel_3);
            app.EVMTextAreaLabel.Text = 'EVM';
            app.EVMTextArea = uitextarea(app.Panel_3);
            app.PhaseIQImbalanceLabel = uilabel(app.Panel_3);
            app.PhaseIQImbalanceLabel.Text = 'Phase IQ Imbalance';
            app.PhaseIQImbalanceTextArea = uitextarea(app.Panel_3);
            app.GainIQImbalanceTextAreaLabel = uilabel(app.Panel_3);
            app.GainIQImbalanceTextAreaLabel.Text = 'Gain IQ Imbalance';
            app.GainIQImbalanceTextArea = uitextarea(app.Panel_3);
            app.ModulationTypeTextAreaLabel = uilabel(app.Panel_3);
            app.ModulationTypeTextAreaLabel.Text = 'Modulation Type';
            app.ModulationTypeTextArea = uitextarea(app.Panel_3);
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '%';
            app.TextArea = uitextarea(app.Panel_3);
            app.Label_2 = uilabel(app.Panel_3);
            app.Label_2.Text = '°';
            app.TextArea_2 = uitextarea(app.Panel_3);
            app.dBTextAreaLabel = uilabel(app.Panel_3);
            app.dBTextAreaLabel.Text = 'dB';
            app.dBTextArea = uitextarea(app.Panel_3);
            app.PortableVectorSignalAnalyzerLabel = uilabel(app.GridLayout5);
            app.PortableVectorSignalAnalyzerLabel.Text = 'Portable Vector Signal Analyzer';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.Panel = uipanel(app.GridLayout3);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RefreshButton = uibutton(app.Panel, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.PauseButton = uibutton(app.Panel, 'push');
            app.PauseButton.Text = 'Pause';
            app.SaveDataCheckBox = uicheckbox(app.GridLayout3);
            app.SaveDataCheckBox.Text = 'Save Data';
            app.LoadConfigFileCheckBox = uicheckbox(app.GridLayout3);
            app.LoadConfigFileCheckBox.Text = 'Load Config File';
            app.Panel_2 = uipanel(app.GridLayout3);
            app.StatusLampLabel = uilabel(app.Panel_2);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.Panel_2);
            app.ConnectionSwitchLabel = uilabel(app.Panel_2);
            app.ConnectionSwitchLabel.Text = 'Connection';
            app.ConnectionSwitch = uiswitch(app.Panel_2, 'slider');
            app.ConnectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectionSwitchValueChanged, true);
            app.TCPcontrolLabel = uilabel(app.GridLayout3);
            app.TCPcontrolLabel.Text = 'TCP control';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_gui_no_grid_layout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end